classdef ET_MEP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label_4      matlab.ui.control.Label
        Button_4     matlab.ui.control.Button
        TextArea_2   matlab.ui.control.TextArea
        Label_3      matlab.ui.control.Label
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_3     matlab.ui.control.ToggleButton
        Button_2     matlab.ui.control.ToggleButton
        Button       matlab.ui.control.ToggleButton
        TextArea     matlab.ui.control.TextArea
        DropDown     matlab.ui.control.DropDown
        Label        matlab.ui.control.Label
        UIAxes3      matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
    end


    methods (Access = private)
        
function ET_PLOT(app,ET,E_obs)          %%绘制拟合图
    y=ET;
    x=E_obs;
            t=polyfit(x,y,1);
mdl = fitlm(x,y);
R2=mdl.Rsquared.Ordinary;
plot(app.UIAxes2,x,y,'*',x,polyval(t,x))
xlabel(app.UIAxes2,' 潜热通量实测值 (W/m^2)');
ylabel(app.UIAxes2,'潜热通量计算值 (W/m^2)');

len_r=(floor(max(x)/50)+1)*50;
len_l=(floor(min(x)/50)-1)*50;            %%坐标轴的范围

axis(app.UIAxes2,[len_l,len_r,len_l,len_r])

if t(2)>0
txt1=['y=' num2str(t(1)) 'x+' num2str(t(2))];
else
    t(2)=abs(t(2));
   txt1=['y=' num2str(t(1)) 'x-' num2str(t(2))];
end
   txt2=['R^2=' num2str(R2)];

pos_x= len_l+20;
pos_y= len_r-20;

text(app.UIAxes2,pos_x,pos_y,txt1)
text(app.UIAxes2,pos_x,pos_y-30,txt2)
        end
        
        function ET_comparison(app,ET,E_obs,T)  %%绘制日变化图
             t=datetime(T,'ConvertFrom','yyyymmdd');
                                x=E_obs;
                                y=ET;
                       beginning=1;
                    if app.Property=='0'
                     
                     ending=min(365,length(ET));
                    elseif app.Property=='1'
                   
                    ending=min(52,length(ET));
                    else
                       ending=12; 
                     
                     end
        
        H1= plot(app.UIAxes,t(beginning:ending),y(beginning:ending)) ;                    
                    hold (app.UIAxes,'on')
                   H2= plot(app.UIAxes,t(beginning:ending),x(beginning:ending));

                  set(H1,'color',[228/255 26/255 28/255],'LineWidth',1.5)
                  set(H2,'color',[4/255 107/255 255/255],'LineWidth',1.5)
                    xlabel(app.UIAxes,'日期  ');
                    ylabel(app.UIAxes,'潜热通量  W/M^2');
                    legend(app.UIAxes,'潜热通量计算值','潜热通量实测值')
        end
        function box_picture1(app,name)         %%月尺度下绘制时序变化图
%UNTITLED 此处提供此函数的摘要
%   此处提供详细说明

name2=strcat(name,'2.mat');

t=1:12;
[ET,E_bos]=MEP_ET(name2);

                                x=E_bos;
                                y=ET;
                       beginning=1;
                    if app.Property=='0'
                     
                     ending=min(365,length(ET));
                    elseif app.Property=='1'
                   
                    ending=min(52,length(ET));
                    else
                       ending=12; 
                     
                     end
        
        H1= plot(app.UIAxes3,t(beginning:ending),y(beginning:ending)) ;                    
                    hold (app.UIAxes3,'on')
                   H2= plot(app.UIAxes3,t(beginning:ending),x(beginning:ending));

                  set(H1,'color',[228/255 26/255 28/255],'LineWidth',1.5)
                  set(H2,'color',[4/255 107/255 255/255],'LineWidth',1.5)
                    xlabel(app.UIAxes3,'月份  ');
                    ylabel(app.UIAxes3,'潜热通量  W/M^2');
                    legend(app.UIAxes3,'潜热通量计算值','潜热通量实测值')


        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          %%初始化 
            app.UIFigure.Name='ET_MEP';
            app.Property='1';

           app.UIAxes3.Visible=0;
            
            %%显示初始图
             value = app.DropDown.Value;
             file=[value,app.Property];
                    [ET,E_obs,T]= MEP_ET(file);  
                                x=E_obs;
                                y=ET;
                     ET_comparison(app,ET,E_obs,T)

                    mdl = fitlm(x,y);
                    R2=mdl.Rsquared.Ordinary;

%% 拟合图
ET_PLOT(app,ET,E_obs);

            
%%

LE=ET;
LE_stic=E_obs;
[~,~,RMSE,~,~,~,KGE,nse]= f_rmse(LE(~isnan(LE) & ~isnan(LE_stic)),LE_stic(~isnan(LE) & ~isnan(LE_stic)));
app.TextArea.Value=['NSE='+string(nse) ,'RMSE='+string(RMSE) ,'KGE='+string(KGE),'R2='+string(R2)];
      
        end

        % Value changed function: DropDown, TextArea
        function DropDownValueChanged(app, event)
           
            value = app.DropDown.Value;   %%下拉框选中对象改变时    
            cla(app.UIAxes)
            cla(app.UIAxes3)
       file=[value app.Property];
   
       [ET,E_obs,T]= MEP_ET(file); 
                             
                                x=E_obs;
                                y=ET;

                      if    app.Property~='2' 
                         ET_comparison(app,ET,E_obs,T) 
                          else 
                          box_picture1(app,value)
                          app.UIAxes.Visible=0;
                      end

%% 拟合图

ET_PLOT(app,ET,E_obs);
  mdl = fitlm(x,y);
  R2=mdl.Rsquared.Ordinary;          
%%

LE=ET;
LE_stic=E_obs;
[~,~,RMSE,~,~,~,KGE,nse]= f_rmse(LE(~isnan(LE) & ~isnan(LE_stic)),LE_stic(~isnan(LE) & ~isnan(LE_stic)));
app.TextArea.Value=['NSE='+string(nse) ,'RMSE='+string(RMSE) ,'KGE='+string(KGE),'R^2='+string(R2)];


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case '日尺度'
                    app.Property='0';
                    cla(app.UIAxes);
                    app.UIAxes.Visible=1;

                    app.UIAxes3.Visible=0;
                case '周尺度'
                    app.Property='1';
                   cla(app.UIAxes)
                    app.UIAxes.Visible=1;
                    app.UIAxes3.Visible=0;


                case '月尺度'
                    app.Property='2';
                   
                    cla(app.UIAxes);
                     
                 app.UIAxes.Visible=0;
                 app.UIAxes3.Visible=1;

            end
            %%切换按钮时，使显示的图能跟着改变
            cla(app.UIAxes3)
            ending=365;
            beginning=1;
            value = app.DropDown.Value;
            file=[value,app.Property];
                    [ET,E_obs,T]= MEP_ET(file);    
                                t=datetime(T,'ConvertFrom','yyyymmdd');
                                x=E_obs;
                                y=ET;

                      
if    app.Property~='2'         
                     
                     ET_comparison(app,ET,E_obs,T)

                   else
                        box_picture1(app,value)
end


%% 拟合图
ET_PLOT(app,ET,E_obs);


mdl = fitlm(x,y);
R2=mdl.Rsquared.Ordinary;

            
%%

LE=ET;
LE_stic=E_obs;
[~,~,RMSE,~,~,~,KGE,nse]= f_rmse(LE(~isnan(LE) & ~isnan(LE_stic)),LE_stic(~isnan(LE) & ~isnan(LE_stic)));
app.TextArea.Value=['NSE='+string(nse) ,'RMSE='+string(RMSE) ,'KGE='+string(KGE),'R^2='+string(R2)];

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            a=app.DropDown.Items;
            b=app.TextArea_2.Value;
            app.DropDown.Items=[a,b];
                               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '站点实测值与MEP模型预测值变化图')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '站点实测值与MEP模型预测值拟合图')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '站点实测值与MEP模型预测值变化图')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '站点';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'AKM'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'AKM';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '时间尺度';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = '日尺度';
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '周尺度';
            app.Button_2.Value = true;
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '月尺度';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '请输入想要添加的站点名称';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '添加站点';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '评价指标';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ET_MEP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end